classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        panel1                         matlab.ui.container.Panel
        Minefield                      matlab.ui.container.GridLayout
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        Count double % Description
         
    end

    methods (Access = public)


        function generateBomb(app, minefield, difficulty)
    % Calculate number of buttons based on field size
    windowSize=app.UIFigure.Position;
    nButtons = numel(minefield);
    centerX = windowSize(1) + (windowSize(3) - 30*size(minefield,2)) / 2 ;
    
    % Initialize button matrix
    buttons = cell(size(minefield));
    app.panel1.Visible='on';
       if difficulty==99
        minefield=minefield';
        
        % Set panel position
        app.panel1.Position = [centerX- 250, windowSize(2), 30*size(minefield,2), 30*size(minefield,1)];
    
       else
        app.panel1.Position=[centerX, windowSize(2), 30*size(minefield,1),30*size(minefield,2) ];
       end
    
    % Calculate button dimensions based on field size
    buttonWidth = 30;
    buttonHeight = 30;
    

    % Generate buttons and positions
    for i = 1:size(minefield,1)
        for j = 1:size(minefield,2)
            % Create a button object
            buttons{i,j} = uibutton(app.panel1, ...,
                "BackgroundColor",[0.7969,0.7969,0.7969],...
                "IconAlignment", "center",...
                "Icon", 'C:\Users\TARIK\Documents\GitHub\Minesweeper\images\square.png',...
                "UserData", [i, j]);
            
            % Set button position
            buttons{i,j}.Position = [j*buttonWidth-30 i*buttonHeight-30 buttonWidth buttonHeight];
            buttons{i,j}.BackgroundColor=[0.7969,0.7969,0.7969];
            % Set button text (initially empty)
            buttons{i,j}.Text = "";
              
            % Add callback function for button click
           
        end
    end
    for i=1:size(minefield,1)
        for j=1:size(minefield,2)
            buttons{i,j}.ButtonPushedFcn = @(src, event) buttonClicked(app, src, buttons);
        end
    end
    %disp(buttons);
    
    % Place bombs randomly based on difficulty
    
       bombLocations=randi(nButtons, 1, difficulty+2);
    

    % Update button text and minefield with bomb information
    for i = 1:length(bombLocations)
        buttonIndex = bombLocations(i);
        [row, col] = ind2sub(size(minefield), buttonIndex);
        minefield(row, col) = -1;
        buttons{row, col}.Tag='bomb';
        
    end
    
    %C:\Users\Administrator\Desktop\Minesweeper\images - copy paste izmedju
    %racunara (Hanan's path)

    for i = 1:size(minefield,1)
        for j = 1:size(minefield,2)
            if strcmp(buttons{i, j}.Tag, '')
               if (i > 1 && i < size(minefield,1) && j > 1 && j < size(minefield,2))
                count = strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                        strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + ...
                        strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb');
               elseif (i == 1 && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == 1 && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == 1)
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb');
               elseif (i == size(minefield,1))
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb');
               elseif (j == 1)
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + ...
                            strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               else
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               end
               buttons{i,j}.Tag = num2str(count);
            end
        end
    end
    
end

function buttonClicked(app, src, buttons)
    row = src.UserData(1);
    col=src.UserData(2);
    if strcmp(src.Tag, 'bomb')
        % Change the color of the panel to red
        disp('Bomb clicked!');
        src.BackgroundColor=[1,0,0];
        %isAlive=false;
        src.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\wrong-bomb.png';
        btns = findall(app.UIFigure, 'Type', 'uibutton');
        %disp(btns);
        for i=2:numel(btns)
            btns(i).ButtonPushedFcn='';
        end
        bombs = findall(app.UIFigure, 'Tag', 'bomb');
        for i=1:numel(bombs)
            if bombs(i) == src
                continue;
            end
            pause(0.3);
            bombs(i).Icon = 'C:\Users\TARIK\Documents\GitHub\Minesweeper\images\bomb.png';
        end
         % RGB values for red
    else

        % Handle the case where the clicked button is not a bomb
        % For example, you might reveal the button or perform other actions
        checkIcon(app,row,col,buttons);
        if src.Tag == '0'
           revealEmpty(app, row, col, buttons);
        end
    end
end

function revealEmpty(app, row, col, buttons)
    if (row==1 && col==1)
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
    elseif (row==1 && col==size(buttons,2))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
    elseif (row==size(buttons,1) && col==size(buttons,2))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (row==size(buttons,1) && col==1)
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (row==1)
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
    elseif (row==size(buttons,1))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
    elseif (col==1)
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (col==size(buttons,2))
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    else
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0' 
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0' 
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
    end
end

function checkIcon(~,row,col,buttons)
    switch buttons{row,col}.Tag
        case '1'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number1.png';
        case '2'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number2.png';
        case '3'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number3.png';
        case '4'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number4.png';
        case '5'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number5.png';
        case '6'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number6.png';
        case '7'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number7.png';
        case '8'
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number8.png';
        otherwise
            buttons{row,col}.Icon='C:\Users\TARIK\Documents\GitHub\Minesweeper\images\number0.png';
    end
end

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.panel1.Visible='off';
            app.panel1.BorderColor=[0.50,0.50,0.50];
            app.panel1.ForegroundColor=[0.50,0.50,0.50];
            app.panel1.HighlightColor=[0.50,0.50,0.50];
            app.Minefield.BackgroundColor=[0.65,0.65,0.65];
            
        end

        % Button pushed function: StartButton
        function StartGame(app, event)
            clc
            difficulty=app.ChoosedifficultyDropDown.Value;
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                case 'Hard'
                    field=zeros(30,16);
                    generateBomb(app,field,99);
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
            
            end
            
            %app.UIFigure.Visible='off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'bomb.png');
            app.ChoosedifficultyDropDownLabel = uilabel(app.UIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.UIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            app.panel1 = uipanel(app.UIFigure);
            app.Minefield = uigridlayout(app.panel1);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end